classdef newver3fullscreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        mmpixelLabel_2             matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        ManualRatioEdit            matlab.ui.control.NumericEditField
        TextArea_2Label            matlab.ui.control.Label
        LiveStatusLabel_2          matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        LoadImageButton            matlab.ui.control.Button
        mmpixelLabel               matlab.ui.control.Label
        MeasureAreaButton          matlab.ui.control.Button
        ScreenshotButton           matlab.ui.control.Button
        SetRatioButton             matlab.ui.control.Button
        RatioLabel                 matlab.ui.control.Label
        StopCameraButton           matlab.ui.control.Button
        StartCameraButton          matlab.ui.control.Button
        UIAxes_Camera              matlab.ui.control.UIAxes
        UnetTab_2                  matlab.ui.container.Tab
        GridLayout                 matlab.ui.container.GridLayout
        ResultTextArea_UNET        matlab.ui.control.TextArea
        Label                      matlab.ui.control.Label
        UNetLabel                  matlab.ui.control.Label
        StatusLabel_UNET           matlab.ui.control.Label
        DisplaySwitchGroup_UNET    matlab.ui.container.ButtonGroup
        Button_8                   matlab.ui.control.RadioButton
        Button_7                   matlab.ui.control.RadioButton
        Button_6                   matlab.ui.control.RadioButton
        Button_5                   matlab.ui.control.RadioButton
        AnalyzeUNETButton          matlab.ui.control.Button
        LoadUNETImageButton        matlab.ui.control.Button
        LoadUNETModelButton        matlab.ui.control.Button
        UIAxes_UNET_Result         matlab.ui.control.UIAxes
        UIAxes_UNET_Original       matlab.ui.control.UIAxes
        RCNNTab                    matlab.ui.container.Tab
        GridLayout3                matlab.ui.container.GridLayout
        ResultTextArea_RCNN        matlab.ui.control.TextArea
        Label_2                    matlab.ui.control.Label
        RCNNLabel                  matlab.ui.control.Label
        StatusLabel_RCNN           matlab.ui.control.Label
        AnalyzeRCNNButton          matlab.ui.control.Button
        UIAxes_RCNN_Original       matlab.ui.control.UIAxes
        UIAxes_RCNN_Result         matlab.ui.control.UIAxes
        Tab_2                      matlab.ui.container.Tab
        GridLayout4                matlab.ui.container.GridLayout
        Dataset                    matlab.ui.control.DropDown
        DatasetLabel               matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Run                        matlab.ui.control.Button
        StatusLabel                matlab.ui.control.Label
        LoadExcel                  matlab.ui.control.Button
        SaveModel                  matlab.ui.control.Button
        LoadModel                  matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_Perform             matlab.ui.control.UIAxes
        Tab_3                      matlab.ui.container.Tab
        GridLayout5                matlab.ui.container.GridLayout
        TextArea_3                 matlab.ui.control.TextArea
        TextAreaLabel              matlab.ui.control.Label
        F2_mag_Edit                matlab.ui.control.NumericEditField
        F2_mag_EditEditFieldLabel  matlab.ui.control.Label
        F1_mag_Edit                matlab.ui.control.NumericEditField
        F1_mag_EditLabel           matlab.ui.control.Label
        EnergyLevelDropDown        matlab.ui.control.DropDown
        Label_5                    matlab.ui.control.Label
        ExitButton                 matlab.ui.control.Button
        Label_8                    matlab.ui.control.Label
        LiveStatusLabel            matlab.ui.control.Label
        PredictAreaButton          matlab.ui.control.Button
        F2_magLabel                matlab.ui.control.Label
        F1_magLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        ExcelData % Description
        TrainedNet
        T_max
        TrainingRecord
        % --- 新增的屬性 ---
        cam                 % 網路攝影機物件
        liveTimer           % 計時器，用來觸發即時影像更新
        ratio = []          % 比例尺 (mm/pixel)
        currentImage        % 儲存當前 UIAxes 上的影像
        screenshotCount = 0;% <--- 請加上這一行來記錄截圖次數
        % --- U-Net 分頁所需的新屬性 ---
        UNET_model           % 儲存載入的 U-Net 網路物件
        UNET_classNames      % 儲存模型的類別名稱
        UNET_originalImage   % 儲存使用者選擇的原始圖片
        UNET_overlayImage    % 儲存產生的分割疊圖
        UNET_confidenceMap   % 儲存產生的信心分數熱圖
        UNET_brightImage     % 儲存亮化效果圖
        UNET_darkImage       % 儲存暗化效果圖
        UNET_targetClassName
         % ==== 串列通訊相關 ====
    s                   % serialport 物件
    serialPort = "COM3" % 預設 COM 埠（改成你的）
    baudRate  = 9600    % 鮑率
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
            try
        ports = serialportlist("available");
        port = app.serialPort;
        if isempty(ports)
            app.LiveStatusLabel.Text = '❌ 找不到任何可用序列埠';
            return;
        end
        if ~ismember(port, ports)
            port = ports(1); % 指定的 COM 不在清單就退回第一個
        end

       
app.s = serialport(app.serialPort, app.baudRate);
pause(2);                             % 等 Arduino 自動重置
configureTerminator(app.s, "LF");     % ✅ 和 Arduino 對齊


        app.LiveStatusLabel.Text = sprintf('✅ 串列已連線：%s @ %d', port, app.baudRate);
    catch ME
        app.LiveStatusLabel.Text = '❌ 串列連線失敗';
        uialert(app.UIFigure, ME.message, '連線失敗');
        app.s = [];
    end
configureTerminator(app.s, "LF");  % 因為 Arduino 用 readStringUntil('\n')
        end

        % Button pushed function: Run
        function RunPushed(app, event)
    % --- 1. 更新UI狀態並禁用按鈕 ---
    app.StatusLabel.Text = '⏳ 模型訓練中，請稍候...';
    app.Run.Enable = 'off';
    app.LoadExcel.Enable = 'off';
    app.LoadModel.Enable = 'off';
    app.SaveModel.Enable = 'off';
    drawnow; 
    % --- 2. 檢查並準備資料 ---
    if isempty(app.ExcelData)
        uialert(app.UIFigure, '請先載入 Excel 檔案。', '錯誤');
        app.Run.Enable = 'on';
        app.LoadExcel.Enable = 'on';
        app.LoadModel.Enable = 'on';
        app.SaveModel.Enable = 'on';
        return;
    end
    
    dataset_id = str2double(app.Dataset.Value);
    start_row = (dataset_id - 1) * 8 + 1;
    end_row = start_row + 6;
    data = app.ExcelData;
    
    if size(data,1) < end_row
        uialert(app.UIFigure, 'Excel 資料不足，請確認格式。', '錯誤');
        app.Run.Enable = 'on';
        app.LoadExcel.Enable = 'on';
        app.LoadModel.Enable = 'on';
        app.SaveModel.Enable = 'on';
        return;
    end
    P_base = data(start_row:start_row+1, 1:5);
    T_matrix = data(start_row+2:start_row+6, 1:5);
    P_all = repelem(P_base, 1, 5);
    T_all = reshape(T_matrix, 1, []);
    app.T_max = max(T_all);
    T_all_normalized = T_all / app.T_max; % 使用新的變數名
    
    % --- 3. 建立與訓練模型 ---
    hidden_layers = [40, 40];
    net = feedforwardnet(hidden_layers, 'trainlm');
    net.inputs{1}.size = 2;
    net = configure(net, P_all, T_all_normalized);
    
    net.divideParam.trainRatio = 0.7;
    net.divideParam.valRatio   = 0.15;
    net.divideParam.testRatio  = 0.15;
    
    net.trainParam.epochs = 1000;
    net.trainParam.goal = 0;
    net.trainParam.showWindow = false; 
    
    [net, tr] = train(net, P_all, T_all_normalized);
    app.TrainedNet = net;
    app.TrainingRecord = tr;
    
    % --- 4. 將訓練效能圖畫到 app.UIAxes_Perform ---
    cla(app.UIAxes_Perform);
    h_fig = plotperform(tr);
    h_ax = h_fig.CurrentAxes;
    copyobj(h_ax.Children, app.UIAxes_Perform);
    title(app.UIAxes_Perform, h_ax.Title.String);
    xlabel(app.UIAxes_Perform, h_ax.XLabel.String);
    ylabel(app.UIAxes_Perform, h_ax.YLabel.String);
    set(app.UIAxes_Perform, 'YScale', 'log');
    grid(app.UIAxes_Perform, 'on');
    close(h_fig);
    
    % --- 5. 在主分頁顯示預測結果 (直接在此繪圖) ---
    out = net(P_all);
    cla(app.UIAxes);
    plot(app.UIAxes, T_all_normalized, '-rd', 'LineWidth', 1.5); 
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, out, '-bo', 'LineWidth', 1.5); 
    hold(app.UIAxes, 'off');
    xlabel(app.UIAxes, 'Data Index');
    ylabel(app.UIAxes, 'Normalized Area');
    legend(app.UIAxes, {'Target', 'NN Output'}, 'Location', 'best');
    grid(app.UIAxes, 'on');
    title(app.UIAxes, ['Dataset ' num2str(dataset_id) ' Prediction']);
        
    % --- 6. 恢復UI狀態 ---
    app.StatusLabel.Text = '✅ 模型訓練完成';
    app.Run.Enable = 'on';
    app.LoadExcel.Enable = 'on';
    app.LoadModel.Enable = 'on';
    app.SaveModel.Enable = 'on';
    
        end

        % Button pushed function: LoadExcel
        function LoadExcelPushed(app, event)
    [file, path] = uigetfile('*.xlsx', '選擇 Excel 檔案');
    if isequal(file, 0)
        app.StatusLabel.Text = '❌ 使用者取消選擇檔案';
        return;
    end
    filepath = fullfile(path, file);

    try
        data = readmatrix(filepath);  % 讀取整張表
        app.ExcelData = data;         % 儲存在屬性中
        app.StatusLabel.Text = ['✅ 成功載入 Excel：' file];
    catch ME
        app.StatusLabel.Text = '❌ Excel 載入失敗';
        disp(ME.message);
    end
        end

        % Button pushed function: SaveModel
        function SaveModelPushed(app, event)
    if isempty(app.TrainedNet) || isempty(app.TrainingRecord) % 檢查兩個屬性
        uialert(app.UIFigure, '尚未訓練模型，請先點選 Run。', '錯誤');
        return;
    end
    
    [file, path] = uiputfile('*.mat', '儲存模型為...');
    if isequal(file, 0)
        app.StatusLabel.Text = '❌ 使用者取消儲存模型';
        return;
    end
    
    model_file = fullfile(path, file);
    try
        net = app.TrainedNet;
        T_max = app.T_max;
        tr = app.TrainingRecord; % 從屬性中取得 tr
        
        % 在儲存時多加入 tr
        save(model_file, 'net', 'T_max', 'tr'); 
        
        app.StatusLabel.Text = ['✅ 模型已儲存：' file];
    catch ME
        app.StatusLabel.Text = '❌ 儲存失敗';
        disp(ME.message);
    end   
        end

        % Button pushed function: LoadModel
        function LoadModelPushed(app, event)
    [file, path] = uigetfile('*.mat', '選擇模型檔案');
    if isequal(file, 0)
        app.StatusLabel.Text = '❌ 使用者取消載入模型';
        return;
    end
    try
        loaded_data = load(fullfile(path, file));
        
        if ~isfield(loaded_data, 'net') || ~isfield(loaded_data, 'T_max') || ~isfield(loaded_data, 'tr')
            uialert(app.UIFigure, '模型檔案不完整，缺少 net, T_max 或 tr 變數。', '錯誤');
            return;
        end
        
        app.TrainedNet = loaded_data.net;
        app.T_max = loaded_data.T_max;
        app.TrainingRecord = loaded_data.tr;
        
        % 更新訓練效能圖
        cla(app.UIAxes_Perform);
        h_fig = plotperform(app.TrainingRecord);
        h_ax = h_fig.CurrentAxes;
        copyobj(h_ax.Children, app.UIAxes_Perform);
        title(app.UIAxes_Perform, h_ax.Title.String);
        xlabel(app.UIAxes_Perform, h_ax.XLabel.String);
        ylabel(app.UIAxes_Perform, h_ax.YLabel.String);
        set(app.UIAxes_Perform, 'YScale', 'log');
        grid(app.UIAxes_Perform, 'on');
        close(h_fig);
        
        % 更新預測結果圖 (直接在此繪圖)
        if ~isempty(app.ExcelData)
            dataset_id = str2double(app.Dataset.Value);
            start_row = (dataset_id - 1) * 8 + 1;
            P_base = app.ExcelData(start_row:start_row+1, 1:5);
            T_matrix = app.ExcelData(start_row+2:start_row+6, 1:5);
            P_all = repelem(P_base, 1, 5);
            T_all = reshape(T_matrix, 1, []);
            T_all_normalized = T_all / app.T_max;
            out = app.TrainedNet(P_all);
            
            cla(app.UIAxes);
            plot(app.UIAxes, T_all_normalized, '-rd', 'LineWidth', 1.5); 
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, out, '-bo', 'LineWidth', 1.5); 
            hold(app.UIAxes, 'off');
            xlabel(app.UIAxes, 'Data Index');
            ylabel(app.UIAxes, 'Normalized Area');
            legend(app.UIAxes, {'Target', 'NN Output'}, 'Location', 'best');
            grid(app.UIAxes, 'on');
            title(app.UIAxes, ['Dataset ' num2str(dataset_id) ' Prediction']);
        end
        
        app.StatusLabel.Text = ['✅ 已載入模型：' file];
    catch ME
        app.StatusLabel.Text = '❌ 模型載入失敗';
        disp(ME.message);
        uialert(app.UIFigure, ['載入失敗：' ME.message], '錯誤');
    end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % 刪除主視窗，即可關閉整個 App
            delete(app.UIFigure);
        end

        % Button pushed function: StartCameraButton
        function StartCameraButtonPushed(app, event)
             % --- 1. 清理舊資源並初始化相機 ---
    cla(app.UIAxes_Camera); % 清空畫面
    app.LiveStatusLabel_2.Text = '⏳ 正在啟動相機...';
    drawnow;
    try
        app.cam = webcam('USB 2.0 Camera'); % 或者 webcam("你的攝影機名稱")
    catch ME
        uialert(app.UIFigure, '無法啟動相機，請確認已連接。', '錯誤');
        app.LiveStatusLabel_2.Text = '❌ 相機啟動失敗';
        disp(ME.message);
        return;
    end

    % --- 2. 拍攝第一張影像並顯示 ---
    app.currentImage = snapshot(app.cam);
    imshow(app.currentImage, 'Parent', app.UIAxes_Camera);

    % --- 3. 建立並啟動計時器以實現即時影像 ---
    % 檢查計時器是否已存在，若存在則刪除重建
    if ~isempty(app.liveTimer) && isvalid(app.liveTimer)
        stop(app.liveTimer);
        delete(app.liveTimer);
    end
    app.liveTimer = timer(...
        'ExecutionMode', 'fixedRate', ... % 重複執行
        'Period', 0.1, ...                % 每 0.1 秒更新一次
        'TimerFcn', @(~,~) app.updateLiveImage());  
     start(app.liveTimer);

    % --- 4. 更新 UI 狀態 ---
    app.StartCameraButton.Enable = 'off';
    app.StopCameraButton.Enable = 'on';
    app.LiveStatusLabel_2.Text = '✅ 相機已開啟';
        end
                % 用於更新即時相機畫面的輔助函式
   function updateLiveImage(app)
            % 檢查相機物件是否仍然有效，防止在關閉相機後計時器還嘗試執行
        if ~isempty(app.cam) && isvalid(app.cam)
                app.currentImage = snapshot(app.cam);
                imshow(app.currentImage, 'Parent', app.UIAxes_Camera);
        end
        end

        % Button pushed function: StopCameraButton
        function StopCameraButtonPushed(app, event)
            if ~isempty(app.liveTimer) && isvalid(app.liveTimer)
                stop(app.liveTimer);
                delete(app.liveTimer);
                app.liveTimer = [];
            end
            if ~isempty(app.cam)
                clear app.cam;
                app.cam = [];
            end
            %cla(app.UIAxes_Camera);
            app.LiveStatusLabel_2.Text = '❌ 相機已關閉';
            app.StartCameraButton.Enable = 'on';
            app.StopCameraButton.Enable = 'off';
        end

        % Button pushed function: MeasureAreaButton
        function MeasureAreaButtonPushed(app, event)
            if isempty(app.currentImage)
                uialert(app.UIFigure, '請先開啟相機並取得影像。', '提示');
                return;
            end
            
            app.LiveStatusLabel_2.Text = '請在影像上圈選多邊形，雙擊結束...';
            roi = drawpolygon(app.UIAxes_Camera);
                        % --- ↓↓↓ 加上這段重要的檢查 ↓↓↓ ---
            if ~isvalid(roi)
                % 如果 roi 物件無效，代表使用者中途按 Esc 取消了操作
                app.LiveStatusLabel_2.Text = '⚠️ 操作已取消。';
                return; % 直接結束這個函式，不再往下執行
            end
            % --- ↑↑↑ 檢查結束 ↑↑↑ ---
            vertices = roi.Position; % 取得多邊形頂點
            
            % 計算面積
            area_pixel = polyarea(vertices(:,1), vertices(:,2));
            if isempty(app.ratio)
                result_text = sprintf('量測面積: %.2f pixels²', area_pixel);
            else
                area_real = area_pixel * (app.ratio^2);
                result_text = sprintf('量測面積: %.2f mm²', area_real);
            end
            app.TextArea.Value = result_text; % 將結果顯示在文字區域
             % --- 傳送到 LCD（新增） ---
if ~isempty(app.s)
    if isempty(app.ratio)
        showLCD(app.s, area_pixel, 1.0); % 沒有比例尺就用像素面積
    else
        showLCD(app.s, area_real, 1.0);  % 有比例尺就用 mm²
    end
end
            % 建立並儲存 mask
            img_size = size(app.currentImage);
            bw = poly2mask(vertices(:,1), vertices(:,2), img_size(1), img_size(2));
            mask = uint8(bw) * 255;
            
            t = datetime('now', 'Format', 'yyyyMMdd_HHmmss');
            outputID = char(t);
            filename_img = ['dataset/input/img_' outputID '.png'];
            filename_mask = ['dataset/target/mask_' outputID '.png'];

            if ~exist('dataset/input', 'dir'); mkdir('dataset/input'); end
            if ~exist('dataset/target', 'dir'); mkdir('dataset/target'); end
            
            imwrite(app.currentImage, filename_img);
            imwrite(mask, filename_mask);
            
            app.LiveStatusLabel_2.Text = ['✅ 影像與 Mask 已儲存'];
            delete(roi); % 刪除輔助多邊形
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
    if ~isempty(app.liveTimer) && isvalid(app.liveTimer)
        stop(app.liveTimer);
        delete(app.liveTimer);
    end
    if ~isempty(app.cam)
        clear app.cam;
    end
    
    % 清理完成後，手動刪除主視窗以確認關閉 App
    delete(app.UIFigure);
            
        end

        % Button pushed function: SetRatioButton
        function SetRatioButtonPushed(app, event)
            if isempty(app.currentImage)
                uialert(app.UIFigure, '請先開啟相機並取得影像。', '提示');
                return;
            end
            
            app.LiveStatusLabel_2.Text = '請在影像上畫一條代表 1mm 的線段...';
            
            % 讓使用者在相機畫面上畫一條線
            roi = drawline(app.UIAxes_Camera);
            
            % 取得線段的起點和終點座標 [x1 y1; x2 y2]
            pos = roi.Position;
            
            % 計算兩點間的像素距離 (歐幾里得距離)
            % 手動計算兩點間的歐幾里得距離 (Euclidean distance)
            delta_x = pos(2, 1) - pos(1, 1); % (x2 - x1)
            delta_y = pos(2, 2) - pos(1, 2); % (y2 - y1)
            pixel_dist = sqrt(delta_x^2 + delta_y^2);
            
            if pixel_dist > 0
                % 假設使用者畫的線段長度為 1mm，計算比例尺
                app.ratio = 1 / pixel_dist; % 單位：mm/pixel
                
                % 更新 UI 上的比例尺標籤
                app.RatioLabel.Text = ['目前比例: ' num2str(app.ratio, '%.4f') ' mm/pixel'];
                app.LiveStatusLabel_2.Text = '✅ 比例尺設定完成';
            else
                app.LiveStatusLabel_2.Text = '❌ 距離為零，設定失敗';
            end
            
            % 刪除畫面上輔助用的線段
            delete(roi);
       
        end

        % Value changed function: ManualRatioEdit
        function ManualRatioEditValueChanged(app, event)

            value = app.ManualRatioEdit.Value;
            
            if isnumeric(value) && value > 0
                % 更新比例尺屬性
                app.ratio = value;
                
                % 更新 UI 上的比例尺標籤
                app.RatioLabel.Text = ['目前比例: ' num2str(app.ratio, '%.4f') ' mm/pixel'];
                app.LiveStatusLabel_2.Text = '✅ 手動設定比例完成';
            else
                app.LiveStatusLabel_2.Text = '❌ 請輸入有效的正數';
            end
        
        end

        % Button pushed function: PredictAreaButton
        function PredictAreaButtonPushed(app, event)
                        % --- 步驟 1: 從 UI 介面獲取使用者輸入 ---
            try
                energy_level = app.EnergyLevelDropDown.Value;
                F1_mag = app.F1_mag_Edit.Value;
                F2_mag = app.F2_mag_Edit.Value;
                
                % 檢查是否為空值
                if isempty(F1_mag) || isempty(F2_mag)
                    uialert(app.UIFigure, '請輸入 F1_mag 和 F2_mag 的數值。', '輸入錯誤');
                    return;
                end
                
            catch ME
                app.LiveStatusLabel.Text = '❌ 讀取輸入失敗';
                disp(['讀取 UI 輸入時發生錯誤: ', ME.message]);
                return;
            end
            
            % --- 步驟 2: 載入對應模型並進行預測 ---
            % 根據能量等級，組合出模型檔案的名稱
            model_filename = ['trained_model_' energy_level '.mat'];
            
            try
                % 檢查模型檔案是否存在
                if ~exist(model_filename, 'file')
                    error_msg = ['找不到模型檔案: ', model_filename, '。請確認模型已訓練並存放在當前路徑。'];
                    uialert(app.UIFigure, error_msg, '模型載入失敗');
                    app.LiveStatusLabel.Text = '❌ 找不到模型檔案';
                    return;
                end
                
                app.LiveStatusLabel.Text = ['⏳ 正在載入模型: ' model_filename];
                drawnow;
                
                S_model = load(model_filename);
                
                % 檢查模型檔案是否包含必要變數
                if ~isfield(S_model, 'net') || ~isfield(S_model, 'T_max')
                    uialert(app.UIFigure, '載入的模型檔案不完整，缺少 "net" 或 "T_max" 變數。', '模型檔案錯誤');
                    app.LiveStatusLabel.Text = '❌ 模型檔案不完整';
                    return;
                end
                
                net = S_model.net;
                T_max = S_model.T_max;
                
                % 準備輸入資料並預測
                input_data = [F1_mag; F2_mag];
                predicted_area_normalized = net(input_data);
                
                % 使用 T_max 進行反正規化，得到真實預測面積
                predicted_area = predicted_area_normalized * T_max;
                
                % --- 步驟 3: 在 UI 上顯示預測結果 ---
                result_str = sprintf('AI 預測面積: %.4f mm²', predicted_area);
                app.TextArea_3.Value = result_str;
                app.LiveStatusLabel.Text = '✅ AI 預測完成';
                if ~isempty(app.s)
    showLCD(app.s, predicted_area, 1.0); % 沒有信心度就先用 1.0（100%）
end
            catch ME
                app.LiveStatusLabel.Text = '❌ AI 預測失敗';
                uialert(app.UIFigure, ['預測失敗: ' ME.message], '錯誤');
                disp(ME.message);
            end
        
        end

        % Button pushed function: ScreenshotButton
        function ScreenshotButtonPushed(app, event)
        if isempty(app.currentImage)
            uialert(app.UIFigure, '請先開啟相機。', '提示');
            return;
        end

        % 累加計數器並產生檔名
        app.screenshotCount = app.screenshotCount + 1;
        filename = sprintf('screenshot_%d.png', app.screenshotCount);

        % 儲存影像
        imwrite(app.currentImage, filename);

        app.LiveStatusLabel_2.Text = ['✅ 已儲存: ' filename];
    
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % --- 步驟 1: 如果相機正在運行，先將其關閉 ---
            % 這樣可以確保我們載入的靜態圖片不會被即時影像覆蓋掉
            if ~isempty(app.liveTimer) && isvalid(app.liveTimer)
                app.LiveStatusLabel_2.Text = '正在停止相機以載入圖片...';
                drawnow;
                % 直接呼叫現有的“關閉相機”函式來完成所有清理工作
                app.StopCameraButtonPushed(event);
            end

            % --- 步驟 2: 讓使用者選擇一個圖片檔案 ---
            [file, path] = uigetfile({'*.png;*.jpg;*.tif;*.bmp', 'Image Files'}, '請選擇一張要分析的圖片');
            if isequal(file, 0)
                app.LiveStatusLabel_2.Text = '⚠️ 已取消選擇圖片。';
                return;
            end
            
            % --- 步驟 3: 讀取、儲存並顯示圖片 ---
            try
                loadedImage = imread(fullfile(path, file));
                
                % 將載入的圖片存到 currentImage 屬性中，這樣其他功能(如量測)才能使用它
                app.currentImage = loadedImage;
                
                % 在 UIAxes_Camera 中顯示載入的靜態圖片
                imshow(app.currentImage, 'Parent', app.UIAxes_Camera);
                app.LiveStatusLabel_2.Text = ['✅ 圖片載入成功: ' file];
                
                % --- 步驟 4: 更新 UI 狀態 ---
                % 既然有了一張靜態圖片，就可以啟用量測和設定比例的按鈕
                app.MeasureAreaButton.Enable = 'on';
                app.SetRatioButton.Enable = 'on';
                app.ScreenshotButton.Enable = 'on';

            catch ME
                uialert(app.UIFigure, ['圖片讀取失敗: ' ME.message], '錯誤');
                app.LiveStatusLabel_2.Text = '❌ 圖片讀取失敗。';
            end

        end

        % Button pushed function: LoadUNETModelButton
        function LoadUNETModelButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', '請選擇 U-Net 模型 .mat 檔案');
            if isequal(file, 0), app.StatusLabel_UNET.Text = '⚠️ 已取消選擇。'; return; end
            
            app.StatusLabel_UNET.Text = '⏳ 正在載入模型...'; drawnow;
            try
                loadedData = load(fullfile(path, file));
                if isfield(loadedData, 'net') && isfield(loadedData, 'classNames')
                    app.UNET_model = loadedData.net;
                    app.UNET_classNames = loadedData.classNames;
                    
                    % --- ↓↓↓ 已修改的邏輯：不再設定 DropDown，而是儲存到屬性中 ↓↓↓ ---
                    % 從 classNames 中找出不是 'background' 的那個類別
                    nonBackground = app.UNET_classNames(~strcmpi(app.UNET_classNames, 'background'));
                    
                    if ~isempty(nonBackground)
                        % 將找到的第一個非背景類別，默默地存入屬性中
                        app.UNET_targetClassName = nonBackground{1};
                    else
                        % 如果模型裡沒有其他類別，就清空
                        app.UNET_targetClassName = ''; 
                        uialert(app.UIFigure, '模型中未找到有效的目標類別。', '警告');
                    end
                    % --- ↑↑↑ 修改結束 ↑↑↑ ---
                    
                    app.StatusLabel_UNET.Text = ['✅ 模型載入成功: ' file];
                    app.LoadUNETImageButton.Enable = 'on'; 
                else
                    uialert(app.UIFigure, '模型檔案缺少 net 或 classNames 變數。', '錯誤');
                end
            catch ME
                uialert(app.UIFigure, ['模型載入失敗: ' ME.message], '錯誤');
            end  
        end

        % Button pushed function: LoadUNETImageButton
        function LoadUNETImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.tif'}, '請選擇一張要分析的圖片');
            if isequal(file, 0), app.StatusLabel_UNET.Text = '⚠️ 已取消選擇。'; return; end
            
            try
                app.UNET_originalImage = imread(fullfile(path, file));
                imshow(app.UNET_originalImage, 'Parent', app.UIAxes_UNET_Original);
                app.UIAxes_UNET_Original.Title.String = '原始圖片';
                
                cla(app.UIAxes_UNET_Result);
                app.UIAxes_UNET_Result.Title.String = '分割結果';
                app.ResultTextArea_UNET.Value = '';
                
                app.StatusLabel_UNET.Text = ['✅ 圖片載入成功: ' file];
                app.AnalyzeUNETButton.Enable = 'on'; % 啟用下一步按鈕
            catch ME
                uialert(app.UIFigure, ['圖片讀取失敗: ' ME.message], '錯誤');
            end   
        end

        % Button pushed function: AnalyzeUNETButton
        function AnalyzeUNETButtonPushed(app, event)
            if isempty(app.UNET_model) || isempty(app.UNET_originalImage)
                uialert(app.UIFigure, '請先載入模型和圖片。', '提示'); return;
            end
            app.StatusLabel_UNET.Text = '⏳ 正在分析...'; drawnow;
            
            try
                net = app.UNET_model;
                classNames = app.UNET_classNames;
                originalImg = app.UNET_originalImage;
                
                % --- 參數設定 ---
                minAreaThreshold_px = 100; 
                morphologyRadius = 15; 
                maskEffectValue = 50;
                
                if isempty(app.ratio)
                    pixelSize_sq_mm = 1.0; 
                    unit_text = 'pixels²';
                else
                    pixelSize_sq_mm = app.ratio^2; 
                    unit_text = 'mm²';
                end
                
                % --- U-Net 預測 ---
                originalSize = size(originalImg, [1, 2]);
                netInputSize = net.Layers(1).InputSize(1:2);
                resizedImg = imresize(originalImg, netInputSize);
                
                % 取得原始的信心分數矩陣 (維度為 H x W x NumClasses)
                scores_resized = predict(net, resizedImg);
                
                % --- ↓↓↓ 核心修改：採用基於「背景信心度」的遮罩生成邏輯 ↓↓↓ ---
                
                % 取得所有像素屬於「背景」的信心分數圖 (假設背景是第1個類別)
                background_scores_resized = scores_resized(:,:,1);
                
                % 將背景信心分數圖縮放回原始圖片尺寸
                background_confidence_map = imresize(background_scores_resized, originalSize);
                
                % 設定一個門檻值，例如 0.5 (50%)
                confidence_threshold = 0.5; 
                
                % 只要一個像素是「背景」的信心分數「低於」這個門檻，我們就把它視為目標
                binaryMask = (background_confidence_map < confidence_threshold);

                % --- ↑↑↑ 新邏輯結束 ↑↑↑ ---

                % 為了 labeloverlay 和其他視覺化功能，我們仍然需要計算最大信心圖和分類圖
                [confMap_resized, predIdx_resized] = max(scores_resized, [], 3);
                predMask_originalSize = imresize(predIdx_resized, originalSize, 'nearest');
                confMap_originalSize = imresize(confMap_resized, originalSize);
                predCategorical = categorical(predMask_originalSize, 1:numel(classNames), classNames);
                
                % --- 產生所有結果圖並存入屬性 ---
                app.UNET_overlayImage = labeloverlay(originalImg, predCategorical, 'Colormap','jet','Transparency',0.4);
                app.UNET_confidenceMap = confMap_originalSize;
                
                % 現在 binaryMask 是由新邏輯產生的，後續處理都基於這個更完整的遮罩
                binaryMask = bwareaopen(binaryMask, round(minAreaThreshold_px/2));
                se = strel('disk', morphologyRadius);
                closedMask = imclose(binaryMask, se);
                
                if size(originalImg,3)==1, tempImg=cat(3,originalImg,originalImg,originalImg); else, tempImg=originalImg; end
                mask3D = repmat(closedMask, [1,1,3]);
                app.UNET_brightImage = imadd(tempImg, uint8(mask3D * maskEffectValue)); % uint8 is generally safe here
                app.UNET_darkImage = imsubtract(tempImg, uint8(mask3D * maskEffectValue));
                
                % --- 預設顯示分割疊圖 ---
                imshow(app.UNET_overlayImage, 'Parent', app.UIAxes_UNET_Result);
                app.UIAxes_UNET_Result.Title.String = '遮罩圖';
                
                % --- 提取數據並顯示 ---
                mainTargetName = classNames{find(~strcmpi(classNames, 'background'), 1)};
                resultText = {sprintf('分析目標: %s ', mainTargetName)};
                cc = bwconncomp(closedMask);
                if cc.NumObjects > 0
                    stats = regionprops(cc, 'PixelIdxList');
                    for i = 1:cc.NumObjects
                        pixel_area = numel(stats(i).PixelIdxList);
                        if pixel_area < minAreaThreshold_px, continue; end
                        
                        conf = mean(confMap_originalSize(stats(i).PixelIdxList));
                        real_area = pixel_area * pixelSize_sq_mm;
                        
                        resultText{end+1} = '--------------------';
                        resultText{end+1} = sprintf('物件 #%d', i);
                        resultText{end+1} = sprintf('  面積: %.6f %s', real_area, unit_text);
                        resultText{end+1} = sprintf('  信心度: %.1f%%', conf*100);
                    end
                end
                 % 傳到 LCD：取面積最大的物件（新增）
if cc.NumObjects > 0 && ~isempty(app.s)
    stats = regionprops(cc, 'PixelIdxList');
    areas_px = cellfun(@numel, {stats.PixelIdxList});
    [~, k] = max(areas_px);
    %bestArea_mm2 = areas_px(k) * pixelSize_sq_mm / 1e6; % 轉 mm²
    bestConf = mean(confMap_originalSize(stats(k).PixelIdxList));
    showLCD(app.s, real_area, bestConf);
end 
                app.ResultTextArea_UNET.Value = resultText;
                app.StatusLabel_UNET.Text = '✅ 分析完成！';
                
            catch ME
                uialert(app.UIFigure, ['分析失敗: ' ME.message], '錯誤');
                app.StatusLabel_UNET.Text = '❌ 分析失敗。';
                disp(ME.getReport('extended'));
            end
        
       
        end

        % Selection changed function: DisplaySwitchGroup_UNET
        function DisplaySwitchGroup_UNETSelectionChanged(app, event)
            if isempty(app.UNET_originalImage), return; end % 尚未分析，不執行
            
            selected = app.DisplaySwitchGroup_UNET.SelectedObject.Text;
            ax = app.UIAxes_UNET_Result;
            cla(ax);
            
            switch selected
                case '顯示遮罩'
                    imshow(app.UNET_overlayImage, 'Parent', ax);
                    ax.Title.String = '遮罩圖';
                case '顯示信心分數熱圖'
                    imagesc(ax, app.UNET_confidenceMap);
                    colormap(ax, 'jet'); colorbar(ax); axis(ax, 'image');
                    ax.Title.String = '信心分數熱圖';
                case '顯示亮化效果圖'
                    imshow(app.UNET_brightImage, 'Parent', ax);
                    ax.Title.String = '亮化效果圖';
                case '顯示暗化效果圖'
                    imshow(app.UNET_darkImage, 'Parent', ax);
                    ax.Title.String = '暗化效果圖';
            end
            
        end

        % Button pushed function: AnalyzeRCNNButton
        function AnalyzeRCNNButtonPushed(app, event)
            % --- 步驟 1: 設定路徑 ---
            pythonScript = 'rcnn_predict.py';
            modelPath = 'maskrcnn_model.pth';
            if ~isfile(pythonScript) || ~isfile(modelPath)
                uialert(app.UIFigure, '找不到 rcnn_predict.py 或 maskrcnn_model.pth！請將它們放在與 App 相同的資料夾中。', '檔案遺失');
                return;
            end

            % --- 步驟 2: 讓使用者選擇圖片 ---
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg'}, '選擇一張要用 R-CNN 分析的圖片');
            if isequal(file, 0), return; end
            
            imgPath = fullfile(path, file);
            imshow(imgPath, 'Parent', app.UIAxes_RCNN_Original);
            app.UIAxes_RCNN_Original.Title.String = '原始圖片';

            % --- 步驟 3: 準備並執行指令 ---
            outputDir = fullfile(pwd, 'rcnn_results');
            if ~exist(outputDir, 'dir'), mkdir(outputDir); end
            outPath = fullfile(outputDir, ['result_' file]);
            
            app.StatusLabel_RCNN.Text = '⏳ 正在呼叫 Python 進行分析...';
            drawnow;

            command = sprintf('python "%s" "%s" "%s" "%s"', pythonScript, modelPath, imgPath, outPath);
            [status, cmdout] = system(command);

            if status == 0 && isfile(outPath)
                imshow(imread(outPath), 'Parent', app.UIAxes_RCNN_Result);
                app.UIAxes_RCNN_Result.Title.String = 'R-CNN 分析結果';
                
                % --- ↓↓↓ 已更新為 U-Net 風格的多行顯示格式 ↓↓↓ ---
                
                if isempty(app.ratio)
                    unit = 'pixels²'; factor = 1;
                    app.StatusLabel_RCNN.Text = '⚠️ 警告: 未設定比例尺，面積以像素為單位。';
                else
                    unit = 'mm²'; factor = app.ratio^2;
                    app.StatusLabel_RCNN.Text = '✅ Python 分析完成！已套用比例尺。';
                end

                tokens = regexp(cmdout, 'Score=([\d\.]+)[\s,]*PixelArea=(\d+)', 'tokens');
                
                if ~isempty(tokens)
                    resultText = {'R-CNN 分析結果:'}; % 建立一個 cell array 並放入標題
                    
                    for i = 1:numel(tokens)
                        score = str2double(tokens{i}{1});
                        pixel_area = str2double(tokens{i}{2});
                        
                        if ~isnan(pixel_area)
                            real_area = pixel_area * factor;
                            
                            % 將每個物件的資訊，分行加入到 cell array 中
                            resultText{end+1} = '--------------------';
                            resultText{end+1} = sprintf('物件 #%d', i);
                            resultText{end+1} = sprintf('  面積: %.4f %s', real_area, unit); % 單位直接放在數字後面
                            resultText{end+1} = sprintf('  信心分數: %.1f%%', score * 100);
                        end
                    end
                    app.ResultTextArea_RCNN.Value = resultText; % 直接設定 cell array 即可完美分行
                 % 傳到 LCD：取面積最大的物件（新增）
if ~isempty(tokens) && ~isempty(app.s)
    n = numel(tokens);
    areas = zeros(1,n); scores = zeros(1,n);
    for i = 1:n
        scores(i) = str2double(tokens{i}{1});
        pxArea = str2double(tokens{i}{2});
        areas(i) = pxArea * factor; % factor=app.ratio^2 或 1（前面已算）
    end
    [~, k] = max(areas);
    showLCD(app.s, areas(k), scores(k));
end
                else
                    app.ResultTextArea_RCNN.Value = {'未偵測到物件。'};
                end
                
                % --- ↑↑↑ 數據處理結束 ↑↑↑ ---
                
            else
                % 失敗時的處理
                errorTitle = 'Python 執行失敗！';
                errorMessage = {'詳細錯誤訊息：'; cmdout};
                uialert(app.UIFigure, strjoin(errorMessage, newline), errorTitle);
                app.StatusLabel_RCNN.Text = '❌ Python 執行失敗。';
                app.ResultTextArea_RCNN.Value = errorMessage;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '即時影像與手動量測';
            app.GridLayout2 = uigridlayout(app.Tab);
            app.UIAxes_Camera = uiaxes(app.GridLayout2);
            app.UIAxes_Camera.XTick = [];
            app.UIAxes_Camera.YTick = [];
            app.StartCameraButton = uibutton(app.GridLayout2, 'push');
            app.StartCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartCameraButtonPushed, true);
            app.StartCameraButton.Text = '開啟相機';
            app.StopCameraButton = uibutton(app.GridLayout2, 'push');
            app.StopCameraButton.ButtonPushedFcn = createCallbackFcn(app, @StopCameraButtonPushed, true);
            app.StopCameraButton.Text = '關閉相機';
            app.RatioLabel = uilabel(app.GridLayout2);
            app.RatioLabel.Text = '目前比例: N/A';
            app.SetRatioButton = uibutton(app.GridLayout2, 'push');
            app.SetRatioButton.ButtonPushedFcn = createCallbackFcn(app, @SetRatioButtonPushed, true);
            app.SetRatioButton.Text = '設定比例 (兩點法)';
            app.ScreenshotButton = uibutton(app.GridLayout2, 'push');
            app.ScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenshotButtonPushed, true);
            app.ScreenshotButton.Text = '擷取畫面';
            app.MeasureAreaButton = uibutton(app.GridLayout2, 'push');
            app.MeasureAreaButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureAreaButtonPushed, true);
            app.MeasureAreaButton.Text = '手動量測面積';
            app.mmpixelLabel = uilabel(app.GridLayout2);
            app.mmpixelLabel.Text = 'mm/pixel';
            app.LoadImageButton = uibutton(app.GridLayout2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = '載入圖片';
            app.Label_9 = uilabel(app.GridLayout2);
            app.Label_9.Text = '智慧超音波空穴預測系統';
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '手動設置比例';
            app.LiveStatusLabel_2 = uilabel(app.GridLayout2);
            app.LiveStatusLabel_2.Text = '';
            app.TextArea_2Label = uilabel(app.GridLayout2);
            app.TextArea_2Label.Text = '結果';
            app.ManualRatioEdit = uieditfield(app.GridLayout2, 'numeric');
            app.ManualRatioEdit.ValueChangedFcn = createCallbackFcn(app, @ManualRatioEditValueChanged, true);
            app.TextArea = uitextarea(app.GridLayout2);
            app.mmpixelLabel_2 = uilabel(app.GridLayout2);
            app.mmpixelLabel_2.Text = 'mm/pixel';
            app.UnetTab_2 = uitab(app.TabGroup);
            app.UnetTab_2.Title = 'U-net';
            app.GridLayout = uigridlayout(app.UnetTab_2);
            app.UIAxes_UNET_Original = uiaxes(app.GridLayout);
            title(app.UIAxes_UNET_Original, '原始圖片')
            zlabel(app.UIAxes_UNET_Original, 'Z')
            app.UIAxes_UNET_Original.XTick = [];
            app.UIAxes_UNET_Original.YTick = [];
            app.UIAxes_UNET_Result = uiaxes(app.GridLayout);
            title(app.UIAxes_UNET_Result, '辨識結果')
            zlabel(app.UIAxes_UNET_Result, 'Z')
            app.UIAxes_UNET_Result.XTick = [];
            app.UIAxes_UNET_Result.YTick = [];
            app.LoadUNETModelButton = uibutton(app.GridLayout, 'push');
            app.LoadUNETModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadUNETModelButtonPushed, true);
            app.LoadUNETModelButton.Text = '載入 U-Net 模型';
            app.LoadUNETImageButton = uibutton(app.GridLayout, 'push');
            app.LoadUNETImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadUNETImageButtonPushed, true);
            app.LoadUNETImageButton.Text = '選擇分析圖片';
            app.AnalyzeUNETButton = uibutton(app.GridLayout, 'push');
            app.AnalyzeUNETButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeUNETButtonPushed, true);
            app.AnalyzeUNETButton.Text = '執行影像分割';
            app.DisplaySwitchGroup_UNET = uibuttongroup(app.GridLayout);
            app.DisplaySwitchGroup_UNET.SelectionChangedFcn = createCallbackFcn(app, @DisplaySwitchGroup_UNETSelectionChanged, true);
            app.DisplaySwitchGroup_UNET.Title = 'Button Group';
            app.Button_5 = uiradiobutton(app.DisplaySwitchGroup_UNET);
            app.Button_5.Text = '顯示遮罩';
            app.Button_5.Value = true;
            app.Button_6 = uiradiobutton(app.DisplaySwitchGroup_UNET);
            app.Button_6.Text = '顯示信心分數熱圖';
            app.Button_7 = uiradiobutton(app.DisplaySwitchGroup_UNET);
            app.Button_7.Text = '顯示亮化效果圖';
            app.Button_8 = uiradiobutton(app.DisplaySwitchGroup_UNET);
            app.Button_8.Text = '顯示暗化效果圖';
            app.StatusLabel_UNET = uilabel(app.GridLayout);
            app.StatusLabel_UNET.Text = '';
            app.UNetLabel = uilabel(app.GridLayout);
            app.UNetLabel.Text = 'U-Net 影像分割';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '數據分析';
            app.ResultTextArea_UNET = uitextarea(app.GridLayout);
            app.RCNNTab = uitab(app.TabGroup);
            app.RCNNTab.Title = 'R-CNN';
            app.GridLayout3 = uigridlayout(app.RCNNTab);
            app.UIAxes_RCNN_Result = uiaxes(app.GridLayout3);
            title(app.UIAxes_RCNN_Result, '辨識結果')
            zlabel(app.UIAxes_RCNN_Result, 'Z')
            app.UIAxes_RCNN_Result.XTick = [];
            app.UIAxes_RCNN_Result.YTick = [];
            app.UIAxes_RCNN_Original = uiaxes(app.GridLayout3);
            title(app.UIAxes_RCNN_Original, '原始圖片')
            zlabel(app.UIAxes_RCNN_Original, 'Z')
            app.UIAxes_RCNN_Original.XTick = [];
            app.UIAxes_RCNN_Original.YTick = [];
            app.AnalyzeRCNNButton = uibutton(app.GridLayout3, 'push');
            app.AnalyzeRCNNButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeRCNNButtonPushed, true);
            app.AnalyzeRCNNButton.Text = '載入圖片並進行 R-CNN 分析';
            app.StatusLabel_RCNN = uilabel(app.GridLayout3);
            app.StatusLabel_RCNN.Text = '';
            app.RCNNLabel = uilabel(app.GridLayout3);
            app.RCNNLabel.Text = 'R-CNN影像框選';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '數據分析';
            app.ResultTextArea_RCNN = uitextarea(app.GridLayout3);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '神經網路';
            app.GridLayout4 = uigridlayout(app.Tab_2);
            app.UIAxes_Perform = uiaxes(app.GridLayout4);
            app.UIAxes = uiaxes(app.GridLayout4);
            app.LoadModel = uibutton(app.GridLayout4, 'push');
            app.LoadModel.ButtonPushedFcn = createCallbackFcn(app, @LoadModelPushed, true);
            app.LoadModel.Text = '載入已訓練模型';
            app.SaveModel = uibutton(app.GridLayout4, 'push');
            app.SaveModel.ButtonPushedFcn = createCallbackFcn(app, @SaveModelPushed, true);
            app.SaveModel.Text = '儲存模型';
            app.LoadExcel = uibutton(app.GridLayout4, 'push');
            app.LoadExcel.ButtonPushedFcn = createCallbackFcn(app, @LoadExcelPushed, true);
            app.LoadExcel.Text = '導入Excel';
            app.StatusLabel = uilabel(app.GridLayout4);
            app.StatusLabel.Text = 'Status';
            app.Run = uibutton(app.GridLayout4, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunPushed, true);
            app.Run.Text = '訓練';
            app.Label_7 = uilabel(app.GridLayout4);
            app.Label_7.Text = '神經網路訓練';
            app.DatasetLabel = uilabel(app.GridLayout4);
            app.DatasetLabel.Text = '數據集';
            app.Dataset = uidropdown(app.GridLayout4);
            app.Dataset.Items = {'0.8J', '1.0J', '1.2J'};
            app.Dataset.ItemsData = {'1', '2', '3'};
            app.Dataset.Value = '1';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '面積預測';
            app.GridLayout5 = uigridlayout(app.Tab_3);
            app.F1_magLabel = uilabel(app.GridLayout5);
            app.F1_magLabel.Text = 'F1_mag';
            app.F2_magLabel = uilabel(app.GridLayout5);
            app.F2_magLabel.Text = 'F2_mag';
            app.PredictAreaButton = uibutton(app.GridLayout5, 'push');
            app.PredictAreaButton.ButtonPushedFcn = createCallbackFcn(app, @PredictAreaButtonPushed, true);
            app.PredictAreaButton.Text = '執行預測';
            app.LiveStatusLabel = uilabel(app.GridLayout5);
            app.LiveStatusLabel.Text = '';
            app.Label_8 = uilabel(app.GridLayout5);
            app.Label_8.Text = '數值特徵預測';
            app.ExitButton = uibutton(app.GridLayout5, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '結束';
            app.Label_5 = uilabel(app.GridLayout5);
            app.Label_5.Text = '能量選單';
            app.EnergyLevelDropDown = uidropdown(app.GridLayout5);
            app.EnergyLevelDropDown.Items = {'0.8J', '1.0J', '1.2J'};
            app.EnergyLevelDropDown.Value = '0.8J';
            app.F1_mag_EditLabel = uilabel(app.GridLayout5);
            app.F1_mag_EditLabel.Text = 'F1_mag_Edit';
            app.F1_mag_Edit = uieditfield(app.GridLayout5, 'numeric');
            app.F2_mag_EditEditFieldLabel = uilabel(app.GridLayout5);
            app.F2_mag_EditEditFieldLabel.Text = 'F2_mag_Edit';
            app.F2_mag_Edit = uieditfield(app.GridLayout5, 'numeric');
            app.TextAreaLabel = uilabel(app.GridLayout5);
            app.TextAreaLabel.Text = '結果';
            app.TextArea_3 = uitextarea(app.GridLayout5);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newver3fullscreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end